classdef updateGUI_v_2022 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        DataLoadTab              matlab.ui.container.Tab
        DataReviewProjectConfigurationPanel  matlab.ui.container.Panel
        ChoosepathTextArea       matlab.ui.control.TextArea
        ChoosepathTextAreaLabel  matlab.ui.control.Label
        BrowseButton             matlab.ui.control.Button
        GraphButton              matlab.ui.control.Button
        GraphingTab              matlab.ui.container.Tab
        TitleEditField           matlab.ui.control.EditField
        TitleEditFieldLabel      matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         % .app object names for use
         date
         title
         filename
         dataFolderPath
    
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraphButton
        function GraphButtonPushed(app, event)
            app.GraphButton.BackgroundColor=[0.4,0.4,0.3];
            app.GraphButton.Text = "Graphing...";
            pause(0.3)
% app.filename = input('Please type the filename including the extension (i.e. filename):\n','s');
% 
% loadData(app.filename);

%%%%%%%%%%%%


            %switches tab
            app.TabGroup.SelectedTab=app.GraphingTab;


        end

        % Value changing function: TitleEditField
        function TitleEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.title= changingValue; %setting the changing value to the variable we made 
            %disp(app.title); %sanity check to make sure everything was going as planned

           app.UIAxes.Title.String=app.title; %sets the title string for the separate
           %  graphing function to the editable title box

        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
          
%opens a modal dialogue box so user can search for the data in their files
app.dataFolderPath = uigetdir('C:\',"Dummy Thicc Data"); % FIX: need to specify
%path more than just C:\ in the future

dataFolderPath=app.dataFolderPath; %app is picky so in order to display you
%have to make a variable and set it equal to the app.variable
%I tried to just straight up have dataFolderPath=uigetdir..... but it
%wouldn't display in the command window cause it didn't recognize it as a
%char or str even though if you set a break point and use
%class(app.Variable) it clearly says its type is "char"..... things be
%weird


app.ChoosepathTextArea.Value=dataFolderPath;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataLoadTab = uitab(app.TabGroup);
            app.DataLoadTab.Title = 'Data Load';
            app.GraphButton = uibutton(app.DataLoadTab, 'push');
            app.GraphButton.ButtonPushedFcn = createCallbackFcn(app, @GraphButtonPushed, true);
            app.GraphButton.Text = 'Graph';
            app.DataReviewProjectConfigurationPanel = uipanel(app.DataLoadTab);
            app.DataReviewProjectConfigurationPanel.Title = 'Data Review Project Configuration';
            app.BrowseButton = uibutton(app.DataReviewProjectConfigurationPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ChoosepathTextAreaLabel = uilabel(app.DataReviewProjectConfigurationPanel);
            app.ChoosepathTextAreaLabel.Text = 'Choose path';
            app.ChoosepathTextArea = uitextarea(app.DataReviewProjectConfigurationPanel);
            app.GraphingTab = uitab(app.TabGroup);
            app.GraphingTab.Title = 'Graphing';
            app.UIAxes = uiaxes(app.GraphingTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TitleEditFieldLabel = uilabel(app.GraphingTab);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.GraphingTab, 'text');
            app.TitleEditField.ValueChangingFcn = createCallbackFcn(app, @TitleEditFieldValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = updateGUI_v_2022

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end